classdef DefineJointLimits < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        OKButton                     matlab.ui.control.Button
        JointHardwareLimitsradLabel  matlab.ui.control.Label
        UITableLimits                matlab.ui.control.Table
    end

    
    properties (Access = private)
        CallingApp
        colNames
        tableRows
        limitData
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, caller, colNames, tableRows)
            app.CallingApp = caller;
            app.colNames = colNames;
            app.tableRows = tableRows;
            app.limitData = zeros(tableRows*2,length(colNames));
            rr = 1;
            for r=1:app.tableRows*2
                if rem(r,2)
                    rowNames{r} = ['Joint ' num2str(rr) ' Lo'];
                else
                    rowNames{r} = ['Joint ' num2str(rr) ' Hi'];
                    rr = rr + 1;
                end
            end
            app.UITableLimits.ColumnName = app.colNames;
            app.UITableLimits.RowName = rowNames;
            app.UITableLimits.Data = app.limitData;
            app.UITableLimits.ColumnEditable = true(1,length(colNames));
        end

        % Cell edit callback: UITableLimits
        function UITableLimitsCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            app.limitData{indices(1),indices(2)} = newData;
        end

        % Button pushed function: OKButton
        function OKButtonPushed(app, event)
        keyboard
        jointLimits = cell(app.tableRows,length(app.colNames));
        for l=1:length(app.colNames)
            rr = 1;
            for r=1:app.tableRows
                jointLimits{r,l} = [app.limitData(rr,l), app.limitData(rr+1,l)];
                rr = rr + 2;
            end
        end
        addJointLimits(app.CallingApp,jointLimits)
        delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UITableLimits = uitable(app.UIFigure);
            app.UITableLimits.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITableLimits.RowName = {};
            app.UITableLimits.ColumnEditable = true;
            app.UITableLimits.CellEditCallback = createCallbackFcn(app, @UITableLimitsCellEdit, true);
            app.JointHardwareLimitsradLabel = uilabel(app.UIFigure);
            app.JointHardwareLimitsradLabel.Text = 'Joint Hardware Limits (rad)';
            app.OKButton = uibutton(app.UIFigure, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.OKButton.Text = 'OK';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DefineJointLimits(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end