classdef DefineNewActuator < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        MomentofInertiakgm2EditField   matlab.ui.control.NumericEditField
        MomentofInertiakgm2EditFieldLabel  matlab.ui.control.Label
        DampingCoeffNmsradEditField    matlab.ui.control.NumericEditField
        DampingCoeffNmsradEditFieldLabel  matlab.ui.control.Label
        ElasticityCoeffNmradEditField  matlab.ui.control.NumericEditField
        ElasticityCoeffNmradEditFieldLabel  matlab.ui.control.Label
        CoMAxisDistmEditField          matlab.ui.control.NumericEditField
        CoMAxisDistmEditFieldLabel     matlab.ui.control.Label
        HornWidthmEditField            matlab.ui.control.NumericEditField
        HornWidthmEditFieldLabel       matlab.ui.control.Label
        HeightmEditField               matlab.ui.control.NumericEditField
        HeightmEditFieldLabel          matlab.ui.control.Label
        WidthmEditField                matlab.ui.control.NumericEditField
        WidthmEditFieldLabel           matlab.ui.control.Label
        LengthmEditField               matlab.ui.control.NumericEditField
        LengthmEditFieldLabel          matlab.ui.control.Label
        MasskgEditField                matlab.ui.control.NumericEditField
        MasskgEditFieldLabel           matlab.ui.control.Label
        SaveActuatorButton             matlab.ui.control.Button
        DistbetweenjointaxisincenterofhornandtheCoMifanyLabel  matlab.ui.control.Label
        DistbetweenedgesofservohornsratherthanjustwidthofbodyLabel  matlab.ui.control.Label
        Image                          matlab.ui.control.Image
        ActuatorNameEditField          matlab.ui.control.EditField
        ActuatorNameEditFieldLabel     matlab.ui.control.Label
        NewActuatorLabel               matlab.ui.control.Label
    end

    
    properties (Access = private)
        name
        mass = 0
        length = 0
        width = 0
        height = 0
        hornWidth = 0
        hornCOMLength = 0
        c = 0
        k = 0
        I = 0
        savePathBase
        CallingApp
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, caller)
            app.CallingApp = caller;
            app.savePathBase = app.CallingApp.savePathBasePub;
            app.Image.ImageSource = [app.savePathBase '\variables\actuators\actuator_diagram.jpg'];
        end

        % Value changed function: ActuatorNameEditField
        function ActuatorNameEditFieldValueChanged(app, event)
            app.name = app.ActuatorNameEditField.Value;
        end

        % Value changed function: MasskgEditField
        function MasskgEditFieldValueChanged(app, event)
            app.mass = app.MasskgEditField.Value;
        end

        % Value changed function: LengthmEditField
        function LengthmEditFieldValueChanged(app, event)
            app.length = app.LengthmEditField.Value;
        end

        % Value changed function: WidthmEditField
        function WidthmEditFieldValueChanged(app, event)
            app.width = app.WidthmEditField.Value;
        end

        % Value changed function: HeightmEditField
        function HeightmEditFieldValueChanged(app, event)
            app.height = app.HeightmEditField.Value;
        end

        % Value changed function: HornWidthmEditField
        function HornWidthmEditFieldValueChanged(app, event)
            app.hornWidth = app.HornWidthmEditField.Value;
        end

        % Value changed function: CoMAxisDistmEditField
        function CoMHornDistmEditFieldValueChanged(app, event)
            app.hornCOMLength = app.CoMAxisDistmEditField.Value;
        end

        % Value changed function: ElasticityCoeffNmradEditField
        function ElasticitycoeffNmradEditFieldValueChanged(app, event)
            app.k = app.ElasticityCoeffNmradEditField.Value;
        end

        % Value changed function: DampingCoeffNmsradEditField
        function DampingcoeffNmsradEditFieldValueChanged(app, event)
            app.c = app.DampingCoeffNmsradEditField.Value;
        end

        % Value changed function: MomentofInertiakgm2EditField
        function MomentofInertiakgm2EditFieldValueChanged(app, event)
            app.I = app.MomentofInertiakgm2EditField.Value;
        end

        % Button pushed function: SaveActuatorButton
        function SaveActuatorButtonPushed(app, event)
            actuator.name = app.name;
            actuator.mass = app.mass;
            actuator.length = app.length;
            actuator.width = app.width;
            actuator.height = app.height;
            actuator.hornWidth = app.hornWidth;
            actuator.hornCOMLength = app.hornCOMLength;
            actuator.c = app.c;
            actuator.k = app.k;
            actuator.I = app.I;
            save([app.savePathBase '\variables\actuators\' app.name '.mat'],'actuator');
            load([app.savePathBase '\variables\actuators\actuators.mat'],'actuators');
            actuators{end+1} = app.name;
            save([app.savePathBase '\variables\actuators\actuators.mat'],'actuators');
            updateActuators(app.CallingApp,actuators)
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.NewActuatorLabel = uilabel(app.UIFigure);
            app.NewActuatorLabel.Text = 'New Actuator';
            app.ActuatorNameEditFieldLabel = uilabel(app.UIFigure);
            app.ActuatorNameEditFieldLabel.Text = 'Actuator Name:';
            app.ActuatorNameEditField = uieditfield(app.UIFigure, 'text');
            app.ActuatorNameEditField.ValueChangedFcn = createCallbackFcn(app, @ActuatorNameEditFieldValueChanged, true);
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'actuator_diagram.jpg';
            app.DistbetweenedgesofservohornsratherthanjustwidthofbodyLabel = uilabel(app.UIFigure);
            app.DistbetweenedgesofservohornsratherthanjustwidthofbodyLabel.Text = '* Dist. between edges of servo horns, rather than just width of body';
            app.DistbetweenjointaxisincenterofhornandtheCoMifanyLabel = uilabel(app.UIFigure);
            app.DistbetweenjointaxisincenterofhornandtheCoMifanyLabel.Text = '* Dist. between joint axis in center of horn and the CoM, if any';
            app.SaveActuatorButton = uibutton(app.UIFigure, 'push');
            app.SaveActuatorButton.ButtonPushedFcn = createCallbackFcn(app, @SaveActuatorButtonPushed, true);
            app.SaveActuatorButton.Text = 'Save Actuator';
            app.MasskgEditFieldLabel = uilabel(app.UIFigure);
            app.MasskgEditFieldLabel.Text = 'Mass (kg):';
            app.MasskgEditField = uieditfield(app.UIFigure, 'numeric');
            app.MasskgEditField.ValueChangedFcn = createCallbackFcn(app, @MasskgEditFieldValueChanged, true);
            app.LengthmEditFieldLabel = uilabel(app.UIFigure);
            app.LengthmEditFieldLabel.Text = 'Length (m):';
            app.LengthmEditField = uieditfield(app.UIFigure, 'numeric');
            app.LengthmEditField.ValueChangedFcn = createCallbackFcn(app, @LengthmEditFieldValueChanged, true);
            app.WidthmEditFieldLabel = uilabel(app.UIFigure);
            app.WidthmEditFieldLabel.Text = 'Width (m):';
            app.WidthmEditField = uieditfield(app.UIFigure, 'numeric');
            app.WidthmEditField.ValueChangedFcn = createCallbackFcn(app, @WidthmEditFieldValueChanged, true);
            app.HeightmEditFieldLabel = uilabel(app.UIFigure);
            app.HeightmEditFieldLabel.Text = 'Height (m):';
            app.HeightmEditField = uieditfield(app.UIFigure, 'numeric');
            app.HeightmEditField.ValueChangedFcn = createCallbackFcn(app, @HeightmEditFieldValueChanged, true);
            app.HornWidthmEditFieldLabel = uilabel(app.UIFigure);
            app.HornWidthmEditFieldLabel.Text = 'Horn Width* (m):';
            app.HornWidthmEditField = uieditfield(app.UIFigure, 'numeric');
            app.HornWidthmEditField.ValueChangedFcn = createCallbackFcn(app, @HornWidthmEditFieldValueChanged, true);
            app.CoMAxisDistmEditFieldLabel = uilabel(app.UIFigure);
            app.CoMAxisDistmEditFieldLabel.Text = 'CoM - Axis Dist.* (m):';
            app.CoMAxisDistmEditField = uieditfield(app.UIFigure, 'numeric');
            app.CoMAxisDistmEditField.ValueChangedFcn = createCallbackFcn(app, @CoMHornDistmEditFieldValueChanged, true);
            app.ElasticityCoeffNmradEditFieldLabel = uilabel(app.UIFigure);
            app.ElasticityCoeffNmradEditFieldLabel.Text = 'Elasticity Coeff. (Nm/rad):';
            app.ElasticityCoeffNmradEditField = uieditfield(app.UIFigure, 'numeric');
            app.ElasticityCoeffNmradEditField.ValueChangedFcn = createCallbackFcn(app, @ElasticitycoeffNmradEditFieldValueChanged, true);
            app.DampingCoeffNmsradEditFieldLabel = uilabel(app.UIFigure);
            app.DampingCoeffNmsradEditFieldLabel.Text = 'Damping Coeff. (Nms/rad):';
            app.DampingCoeffNmsradEditField = uieditfield(app.UIFigure, 'numeric');
            app.DampingCoeffNmsradEditField.ValueChangedFcn = createCallbackFcn(app, @DampingcoeffNmsradEditFieldValueChanged, true);
            app.MomentofInertiakgm2EditFieldLabel = uilabel(app.UIFigure);
            app.MomentofInertiakgm2EditFieldLabel.Text = 'Moment of Inertia (kgm^2):';
            app.MomentofInertiakgm2EditField = uieditfield(app.UIFigure, 'numeric');
            app.MomentofInertiakgm2EditField.ValueChangedFcn = createCallbackFcn(app, @MomentofInertiakgm2EditFieldValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DefineNewActuator(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end