classdef DefineNewConfig < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        HardwarelimitsaddedLabel        matlab.ui.control.Label
        AddhardwarelimitsButton         matlab.ui.control.Button
        SaveConfigurationButton         matlab.ui.control.Button
        ActuatedJointsLabel             matlab.ui.control.Label
        UITableAct                      matlab.ui.control.Table
        MostProximalLevDepJointNumberLabel  matlab.ui.control.Label
        UITableCTr                      matlab.ui.control.Table
        JointsLabel                     matlab.ui.control.Label
        UITableJoints                   matlab.ui.control.Table
        BodiesLabel                     matlab.ui.control.Label
        GenerateEmptyBodyandJointTablesButton  matlab.ui.control.Button
        UITableBodies                   matlab.ui.control.Table
        AnimatlabProjFileAddressEditField  matlab.ui.control.EditField
        AnimatlabProjFileAddressEditFieldLabel  matlab.ui.control.Label
        AnimatlabOrganismNameEditField  matlab.ui.control.EditField
        OrganismNameLabel               matlab.ui.control.Label
        TotalNumberofJointsEditField    matlab.ui.control.NumericEditField
        TotalNumberofJointsEditFieldLabel  matlab.ui.control.Label
        ConfigurationNameEditField      matlab.ui.control.EditField
        ConfigurationNameLabel          matlab.ui.control.Label
        NewConfigurationLabel           matlab.ui.control.Label
        MaxNumberofJointsinLegEditField  matlab.ui.control.NumericEditField
        MaxNumberofJointsinLegEditFieldLabel  matlab.ui.control.Label
        NumberofLegsEditField           matlab.ui.control.NumericEditField
        NumberofLegsEditFieldLabel      matlab.ui.control.Label
    end

    
    properties (Access = private)
        CallingApp
        configName = 7
        numJoints = 22
        numLegs = 6
        organism_name
        proj_file
        tableRows = 8;
        bodydata
        jointdata
        actdata
        ctrdata
        colNames
        DefineLimitsApp
        jointLimits = [];
    end
    
    methods (Access = public)
        
        function addJointLimits(app,jointLimits)
            app.jointLimits = jointLimits;
            app.SaveConfigurationButton.Enable = 'on';
            app.AddhardwarelimitsButton.Enable = 'on';
            app.HardwarelimitsaddedLabel.Visible = 'on';
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, caller)
            app.CallingApp = caller;
            app.NumberofLegsEditField.Value = app.numLegs;
            app.MaxNumberofJointsinLegEditField.Value = app.tableRows-1;
            app.BodiesLabel.Visible = 'off';
            app.UITableBodies.Visible = 'off';
            app.JointsLabel.Visible = 'off';
            app.UITableJoints.Visible = 'off';
            app.MostProximalLevDepJointNumberLabel.Visible = 'off';
            app.UITableCTr.Visible = 'off';
            app.ActuatedJointsLabel.Visible = 'off';
            app.UITableAct.Visible = 'off';
            app.AddhardwarelimitsButton.Visible = 'off';
            app.HardwarelimitsaddedLabel.Visible = 'off';
        end

        % Value changed function: ConfigurationNameEditField
        function ConfigurationNameEditFieldValueChanged(app, event)
            app.configName = app.ConfigurationNameEditField.Value;
        end

        % Value changed function: AnimatlabOrganismNameEditField
        function AnimatlabOrganismNameEditFieldValueChanged(app, event)
            app.organism_name = app.AnimatlabOrganismNameEditField.Value;
            
        end

        % Value changed function: AnimatlabProjFileAddressEditField
        function AnimatlabProjFileAddressEditFieldValueChanged(app, event)
            app.proj_file = app.AnimatlabProjFileAddressEditField.Value;
            
        end

        % Value changed function: NumberofLegsEditField
        function NumberofLegsEditFieldValueChanged(app, event)
            app.numLegs = app.NumberofLegsEditField.Value;
            
        end

        % Value changed function: TotalNumberofJointsEditField
        function TotalNumberofJointsEditFieldValueChanged(app, event)
            app.numJoints = app.TotalNumberofJointsEditField.Value;
            
        end

        % Value changed function: MaxNumberofJointsinLegEditField
        function MaxNumberofJointsinLegEditFieldValueChanged(app, event)
            app.tableRows = app.MaxNumberofJointsinLegEditField.Value+1;
            
        end

        % Button pushed function: GenerateEmptyBodyandJointTablesButton
        function GenerateEmptyBodyandJointTablesButtonPushed(app, event)
            app.BodiesLabel.Visible = 'on';
            app.UITableBodies.Visible = 'on';
            app.JointsLabel.Visible = 'on';
            app.UITableJoints.Visible = 'on';
            app.MostProximalLevDepJointNumberLabel.Visible = 'on';
            app.UITableCTr.Visible = 'on';
            app.ActuatedJointsLabel.Visible = 'on';
            app.UITableAct.Visible = 'on';
            app.AddhardwarelimitsButton.Visible = 'on';

            app.bodydata = cell(app.tableRows, app.numLegs);
            app.jointdata = cell(app.tableRows, app.numLegs);
            app.ctrdata = 2*ones(1,app.numLegs);
            app.actdata = [2*ones(1,app.numLegs);app.tableRows*ones(1,app.numLegs)];

            for c=1:app.numLegs
                app.colNames{c} = ['Leg ' num2str(c)];
                app.jointdata{1,c} = '[]';
            end
            for r=1:app.tableRows
                bodyRowNames{r} = ['Body ' num2str(r)];
            end
            for r=1:app.tableRows
                jointRowNames{r} = ['Joint ' num2str(r)];
            end

            app.UITableBodies.ColumnName = app.colNames;
            app.UITableBodies.RowName = bodyRowNames;
            app.UITableBodies.Data = app.bodydata;
            app.UITableBodies.ColumnEditable = true(1,app.numLegs);

            app.UITableJoints.ColumnName = app.colNames;
            app.UITableJoints.RowName = jointRowNames;
            app.UITableJoints.Data = app.jointdata;
            app.UITableJoints.ColumnEditable = true(1,app.numLegs);

            app.UITableCTr.ColumnName = app.colNames;
            app.UITableCTr.Data = app.ctrdata;
            app.UITableCTr.ColumnEditable = true(1,app.numLegs);

            app.UITableAct.ColumnName = app.colNames;
            app.UITableAct.RowName = {'First', 'Last'};
            app.UITableAct.Data = app.actdata;
            app.UITableAct.ColumnEditable = true(1,app.numLegs);

        end

        % Cell edit callback: UITableBodies
        function UITableBodiesCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            app.bodydata{indices(1),indices(2)} = newData;
        end

        % Cell edit callback: UITableJoints
        function UITableJointsCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            app.jointdata{indices(1),indices(2)} = newData;
        end

        % Cell edit callback: UITableCTr
        function UITableCTrCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            app.ctrdata(indices(1),indices(2)) = newData;
        end

        % Cell edit callback: UITableAct
        function UITableActCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            app.actdata(indices(1),indices(2)) = newData;
        end

        % Button pushed function: SaveConfigurationButton
        function SaveConfigurationButtonPushed(app, event)

            for r=1:app.tableRows
                for c=1:app.numLegs
                    if isempty(app.jointdata{r,c})
                        app.jointdata{r,c} = '[]';
                    end
                    if isempty(app.bodydata{r,c})
                        app.bodydata{r,c} = '[]';
                    end
                    jointVecs{1,c} = app.actdata(1,c):app.actdata(2,c);
                end
            end

            varStruct.bodies = app.bodydata;
            varStruct.configName = app.configName;
            varStruct.ctr = app.ctrdata;
            varStruct.joints = app.jointdata;
            varStruct.jointVecs = jointVecs;
            varStruct.numJoints = app.numJoints;
            varStruct.numLegs = app.numLegs;
            varStruct.organism_name = app.organism_name;
            varStruct.proj_file = app.proj_file;
            varStruct.jointLimits = app.jointLimits;
            savePathBase = app.CallingApp.savePathBasePub;
            save([savePathBase '\variables\configs\' app.configName ' Config.mat'],'-struct','varStruct');
            load([savePathBase '\variables\configs\configs.mat'],'configs');
            configs{end+1} = app.configName;
            save([savePathBase '\variables\configs\configs.mat'],'configs');
            updateDropdown(app.CallingApp,configs)
            delete(app)
        end

        % Button pushed function: AddhardwarelimitsButton
        function AddhardwarelimitsButtonPushed(app, event)
            app.SaveConfigurationButton.Enable = 'off';
            app.AddhardwarelimitsButton.Enable = 'off';
            app.DefineLimitsApp = DefineJointLimits(app,app.colNames,app.tableRows);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.NumberofLegsEditFieldLabel = uilabel(app.UIFigure);
            app.NumberofLegsEditFieldLabel.Text = 'Number of Legs:';
            app.NumberofLegsEditField = uieditfield(app.UIFigure, 'numeric');
            app.NumberofLegsEditField.ValueChangedFcn = createCallbackFcn(app, @NumberofLegsEditFieldValueChanged, true);
            app.MaxNumberofJointsinLegEditFieldLabel = uilabel(app.UIFigure);
            app.MaxNumberofJointsinLegEditFieldLabel.Text = 'Max Number of Joints in Leg:';
            app.MaxNumberofJointsinLegEditField = uieditfield(app.UIFigure, 'numeric');
            app.MaxNumberofJointsinLegEditField.ValueChangedFcn = createCallbackFcn(app, @MaxNumberofJointsinLegEditFieldValueChanged, true);
            app.NewConfigurationLabel = uilabel(app.UIFigure);
            app.NewConfigurationLabel.Text = 'New Configuration';
            app.ConfigurationNameLabel = uilabel(app.UIFigure);
            app.ConfigurationNameLabel.Text = 'Configuration Name:';
            app.ConfigurationNameEditField = uieditfield(app.UIFigure, 'text');
            app.ConfigurationNameEditField.ValueChangedFcn = createCallbackFcn(app, @ConfigurationNameEditFieldValueChanged, true);
            app.TotalNumberofJointsEditFieldLabel = uilabel(app.UIFigure);
            app.TotalNumberofJointsEditFieldLabel.Text = 'Total Number of Joints:';
            app.TotalNumberofJointsEditField = uieditfield(app.UIFigure, 'numeric');
            app.TotalNumberofJointsEditField.ValueChangedFcn = createCallbackFcn(app, @TotalNumberofJointsEditFieldValueChanged, true);
            app.OrganismNameLabel = uilabel(app.UIFigure);
            app.OrganismNameLabel.Text = 'Animatlab Organism Name:';
            app.AnimatlabOrganismNameEditField = uieditfield(app.UIFigure, 'text');
            app.AnimatlabOrganismNameEditField.ValueChangedFcn = createCallbackFcn(app, @AnimatlabOrganismNameEditFieldValueChanged, true);
            app.AnimatlabProjFileAddressEditFieldLabel = uilabel(app.UIFigure);
            app.AnimatlabProjFileAddressEditFieldLabel.Text = 'Animatlab Proj. File Address:';
            app.AnimatlabProjFileAddressEditField = uieditfield(app.UIFigure, 'text');
            app.AnimatlabProjFileAddressEditField.ValueChangedFcn = createCallbackFcn(app, @AnimatlabProjFileAddressEditFieldValueChanged, true);
            app.UITableBodies = uitable(app.UIFigure);
            app.UITableBodies.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'; 'Column 5'; 'Column 6'};
            app.UITableBodies.RowName = {};
            app.UITableBodies.ColumnEditable = true;
            app.UITableBodies.CellEditCallback = createCallbackFcn(app, @UITableBodiesCellEdit, true);
            app.GenerateEmptyBodyandJointTablesButton = uibutton(app.UIFigure, 'push');
            app.GenerateEmptyBodyandJointTablesButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateEmptyBodyandJointTablesButtonPushed, true);
            app.GenerateEmptyBodyandJointTablesButton.Text = 'Generate Empty Body and Joint Tables';
            app.BodiesLabel = uilabel(app.UIFigure);
            app.BodiesLabel.Text = 'Bodies';
            app.UITableJoints = uitable(app.UIFigure);
            app.UITableJoints.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'; 'Column 5'; 'Column 6'};
            app.UITableJoints.RowName = {};
            app.UITableJoints.ColumnEditable = true;
            app.UITableJoints.CellEditCallback = createCallbackFcn(app, @UITableJointsCellEdit, true);
            app.JointsLabel = uilabel(app.UIFigure);
            app.JointsLabel.Text = 'Joints';
            app.UITableCTr = uitable(app.UIFigure);
            app.UITableCTr.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITableCTr.RowName = {};
            app.UITableCTr.CellEditCallback = createCallbackFcn(app, @UITableCTrCellEdit, true);
            app.MostProximalLevDepJointNumberLabel = uilabel(app.UIFigure);
            app.MostProximalLevDepJointNumberLabel.Text = 'Most Proximal Lev/Dep Joint Number';
            app.UITableAct = uitable(app.UIFigure);
            app.UITableAct.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITableAct.RowName = {};
            app.UITableAct.CellEditCallback = createCallbackFcn(app, @UITableActCellEdit, true);
            app.ActuatedJointsLabel = uilabel(app.UIFigure);
            app.ActuatedJointsLabel.Text = 'Actuated Joints';
            app.SaveConfigurationButton = uibutton(app.UIFigure, 'push');
            app.SaveConfigurationButton.ButtonPushedFcn = createCallbackFcn(app, @SaveConfigurationButtonPushed, true);
            app.SaveConfigurationButton.Text = 'Save Configuration';
            app.AddhardwarelimitsButton = uibutton(app.UIFigure, 'push');
            app.AddhardwarelimitsButton.ButtonPushedFcn = createCallbackFcn(app, @AddhardwarelimitsButtonPushed, true);
            app.AddhardwarelimitsButton.Text = 'Add hardware limits (for robot only)';
            app.HardwarelimitsaddedLabel = uilabel(app.UIFigure);
            app.HardwarelimitsaddedLabel.Text = 'Hardware limits added';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DefineNewConfig(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end