classdef RunSolver < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        ConfigurationSavedLabel         matlab.ui.control.Label
        SaveNewBodyStructureLocationButton  matlab.ui.control.Button
        BodyStructureFileLocationEditField  matlab.ui.control.EditField
        ConfigurationFileLocationLabel  matlab.ui.control.Label
        CalculateneuralparamsButtonGroup  matlab.ui.container.ButtonGroup
        YesButton_4                     matlab.ui.control.RadioButton
        NoButton_4                      matlab.ui.control.RadioButton
        IpsalNormPhaseAngleEditField    matlab.ui.control.NumericEditField
        IpsalNormPhaseAngleEditFieldLabel  matlab.ui.control.Label
        ContralNormPhaseAngleEditField  matlab.ui.control.NumericEditField
        ContralNormPhaseAngleEditFieldLabel  matlab.ui.control.Label
        LoadNameDropDown                matlab.ui.control.DropDown
        LoadNameDropDownLabel           matlab.ui.control.Label
        TerrainTypeDropDown             matlab.ui.control.DropDown
        TerrainTypeLabel                matlab.ui.control.Label
        FormultipleactuatorsdefinedirectlyinacustomIfileLabel  matlab.ui.control.Label
        PrimaryactuatorDropDown         matlab.ui.control.DropDown
        PrimaryactuatorDropDownLabel    matlab.ui.control.Label
        ParameterssavedLabel            matlab.ui.control.Label
        LoadButton                      matlab.ui.control.Button
        LoadParametersButton            matlab.ui.control.Button
        SaveButton                      matlab.ui.control.Button
        SaveNameEditField               matlab.ui.control.EditField
        SaveNameEditFieldLabel          matlab.ui.control.Label
        EnterIfilenameEditField         matlab.ui.control.EditField
        EnterIfilenameLabel             matlab.ui.control.Label
        UsecustomIfileButtonGroup       matlab.ui.container.ButtonGroup
        YesButton_3                     matlab.ui.control.RadioButton
        NoButton_3                      matlab.ui.control.RadioButton
        SaveParametersButton            matlab.ui.control.Button
        UITable                         matlab.ui.control.Table
        MaxJointVelocityradsEditField   matlab.ui.control.NumericEditField
        MaxJointVelocityradsLabel       matlab.ui.control.Label
        SpringKNmradEditField           matlab.ui.control.NumericEditField
        SpringKNmradLabel               matlab.ui.control.Label
        MaxBodyRotationradEditField     matlab.ui.control.NumericEditField
        BodyRotationradLabel            matlab.ui.control.Label
        MaxBodyTranslationmEditField    matlab.ui.control.NumericEditField
        MaxBodyTranslationmEditFieldLabel  matlab.ui.control.Label
        StanceDutyEditField             matlab.ui.control.NumericEditField
        StanceDutyLabel                 matlab.ui.control.Label
        SwingDurationsEditField         matlab.ui.control.NumericEditField
        SwingDurationsEditFieldLabel    matlab.ui.control.Label
        StartSolverButton               matlab.ui.control.Button
        SteppingParametersLabel         matlab.ui.control.Label
        ForarobotButtonGroup            matlab.ui.container.ButtonGroup
        YesButton_2                     matlab.ui.control.RadioButton
        NoButton_2                      matlab.ui.control.RadioButton
        RunasstaticButtonGroup          matlab.ui.container.ButtonGroup
        YesButton                       matlab.ui.control.RadioButton
        NoButton                        matlab.ui.control.RadioButton
        BodyStructureNameDropDown       matlab.ui.control.DropDown
        ConfigurationLabel              matlab.ui.control.Label
        SaveLocationTextArea            matlab.ui.control.TextArea
        SaveLocationLabel               matlab.ui.control.Label
    end

    
    properties (Access = private)
        NewConfigApp
        NewActApp
        savePathBase
        swT
        stDuty
        bodyT
        bodyR
        sprK
        maxThetaDot
        configName
        stepH
        floorL
        Ifilename = 'findIMatrixGeneric'
        paramSaveName
        paramLoadName
        actuatorName
        substrateShape
        contralateralPhase
        ipsalateralPhase
        runNeuralParams
        projectFile
        paramNames
    end
    
    properties (Access = public)
        savePathBasePub % Description
    end
    
    methods (Access = public)
        
        function updateDropdown(app,configs)
            app.BodyStructureNameDropDown.Items = [configs, 'New Config'];
            app.BodyStructureNameDropDown.Value = configs{end};
        end

        function updateActuators(app,actuators)
            app.PrimaryactuatorDropDown.Items = [actuators, 'New Actuator'];
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

            app.savePathBase = fileparts(which('RunSolver.mlapp'));
            app.savePathBasePub = app.savePathBase;
            cd(app.savePathBase)
            load([app.savePathBase '\variables\configs\configs.mat'],'configs');
            load([app.savePathBase '\variables\step parameters\defaultStepParameters.mat']);
            load([app.savePathBase '\variables\actuators\actuators.mat'],'actuators');
            load([app.savePathBase '\variables\step parameters\parameterNames.mat'],'parameterNames');
            app.paramNames = parameterNames;
            app.swT = swingDuration;
            app.stDuty = stanceDuty;
            app.bodyT = bodyTranslation;
            app.bodyR = bodyRotation;
            app.sprK = springK;
            app.stepH = stepHeight';
            app.floorL = floorLevel';
            app.maxThetaDot = maxJointVelocity;
            app.actuatorName = actuators{1};
            app.PrimaryactuatorDropDown.Items = [actuators, 'New Actuator'];
            app.BodyStructureNameDropDown.Items = [configs, 'New Config'];
            app.LoadNameDropDown.Items = app.paramNames;
            app.configName = configs{1};
            load([app.savePathBase '\variables\configs\' app.configName ' Config.mat'],'proj_file');
            app.projectFile = proj_file;
            app.BodyStructureFileLocationEditField.Value = app.projectFile;
            app.SaveLocationTextArea.Value = app.savePathBase;
            app.SwingDurationsEditField.Value = swingDuration;
            app.StanceDutyEditField.Value = stanceDuty;
            app.MaxBodyTranslationmEditField.Value = bodyTranslation;
            app.MaxBodyRotationradEditField.Value = bodyRotation;
            app.SpringKNmradEditField.Value = springK;
            app.MaxJointVelocityradsEditField.Value = maxJointVelocity;
            app.ipsalateralPhase = 0.5;
            app.contralateralPhase = 0.5;
            tableData = [stepHeight'; floorLevel'];
            app.UITable.Data = tableData;
            app.EnterIfilenameEditField.Visible = 'off';
            app.EnterIfilenameLabel.Visible = 'off';
            app.SaveNameEditField.Visible = 'off';
            app.SaveNameEditFieldLabel.Visible = 'off';
            app.ConfigurationSavedLabel.Visible = 'off';
            app.SaveNewBodyStructureLocationButton.Enable = 'off';
            app.SaveButton.Visible = 'off';
            app.LoadButton.Visible = 'off';
            app.ParameterssavedLabel.Visible = 'off';
            app.substrateShape = 'Flat';
            app.TerrainTypeDropDown.Value = 'Flat';
            app.LoadNameDropDown.Visible = 'off';
            app.LoadNameDropDownLabel.Visible = 'off';
            app.IpsalNormPhaseAngleEditField.Value = app.ipsalateralPhase;
            app.ContralNormPhaseAngleEditField.Value = app.contralateralPhase;
            app.runNeuralParams = 'No';
        end

        % Value changed function: BodyStructureNameDropDown
        function BodyStructureNameDropDownValueChanged(app, event)
            app.configName = app.BodyStructureNameDropDown.Value;
            if strcmp(app.configName, 'New Config')
                app.NewConfigApp = DefineNewConfig(app);
            end
            app.configName = app.BodyStructureNameDropDown.Value;
            load([app.savePathBase '\variables\configs\' app.configName ' Config.mat'],'proj_file');
            app.projectFile = proj_file;
            app.BodyStructureFileLocationEditField.Value = app.projectFile;
        end

        % Value changed function: SwingDurationsEditField
        function SwingDurationsEditFieldValueChanged(app, event)
            app.swT = app.SwingDurationsEditField.Value;
        end

        % Value changed function: StanceDutyEditField
        function StanceDutyEditFieldValueChanged(app, event)
            app.stDuty = app.StanceDutyEditField.Value;
        end

        % Value changed function: MaxBodyTranslationmEditField
        function MaxBodyTranslationmEditFieldValueChanged(app, event)
            app.bodyT = app.MaxBodyTranslationmEditField.Value;
        end

        % Value changed function: MaxBodyRotationradEditField
        function MaxBodyRotationradEditFieldValueChanged(app, event)
            app.bodyR = app.MaxBodyRotationradEditField.Value;
        end

        % Value changed function: SpringKNmradEditField
        function SpringKNmradEditFieldValueChanged(app, event)
            app.sprK = app.SpringKNmradEditField.Value;
        end

        % Value changed function: MaxJointVelocityradsEditField
        function MaxJointVelocityradsEditFieldValueChanged(app, event)
            app.maxThetaDot = app.MaxJointVelocityradsEditField.Value;
        end

        % Cell edit callback: UITable
        function UITableCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            if indices(1) == 1
                app.stepH(indices(2)) = newData;
            else
                app.floorL(indices(2)) = newData;
            end
        end

        % Button pushed function: SaveParametersButton
        function SaveParametersButtonPushed(app, event)
            app.ParameterssavedLabel.Visible = 'off';
            app.SaveNameEditField.Visible = 'on';
            app.SaveNameEditFieldLabel.Visible = 'on';
            app.SaveParametersButton.Enable = 'off';
            app.SaveButton.Visible = 'on';
            app.SaveButton.Enable = 'off';
        end

        % Button pushed function: StartSolverButton
        function StartSolverButtonPushed(app, event)
            saveName = app.configName;
            if app.YesButton.Value
                saveName = [app.configName '_Massless'];
            end
            if app.YesButton_2.Value
                saveName = ['Physical ' app.configName];
                isRobot = 1;
            else
                isRobot = 0;
            end 
            close all
            design_drosophibot(app.savePathBase,app.configName,saveName,app.swT,app.stDuty,app.stepH,app.floorL,app.sprK,app.maxThetaDot,app.bodyT,app.bodyR,app.Ifilename,app.actuatorName,app.substrateShape,app.contralateralPhase,app.ipsalateralPhase, app.runNeuralParams)

        end

        % Value changed function: EnterIfilenameEditField
        function EnterIfilenameEditFieldValueChanged(app, event)
            app.Ifilename = app.EnterIfilenameEditField.Value;
        end

        % Selection changed function: UsecustomIfileButtonGroup
        function UsecustomIfileButtonGroupSelectionChanged(app, event)
            selectedButton = app.UsecustomIfileButtonGroup.SelectedObject;
            if strcmp(selectedButton.Text,'Yes')
                app.EnterIfilenameEditField.Visible = 'on';
                app.EnterIfilenameLabel.Visible = 'on';
            else
                app.EnterIfilenameEditField.Visible = 'off';
                app.EnterIfilenameLabel.Visible = 'off';
                app.Ifilename = 'findIMatrixGeneric';
            end
        end

        % Value changed function: SaveNameEditField
        function SaveNameEditFieldValueChanged(app, event)
            app.paramSaveName = app.SaveNameEditField.Value;
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            varStruct.swingDuration = app.swT;
            varStruct.stanceDuty = app.stDuty;
            varStruct.bodyTranslation = app.bodyT;
            varStruct.bodyRotation = app.bodyR;
            varStruct.springK = app.sprK;
            varStruct.stepHeight = app.stepH';
            varStruct.floorLevel = app.floorL';
            varStruct.maxJointVelocity = app.maxThetaDot;
            save([app.savePathBase '\variables\step parameters\' app.paramSaveName '.mat'],'-struct','varStruct')
            app.paramNames{end+1} = app.paramSaveName;
            parameterNames = app.paramNames;
            save([app.savePathBase '\variables\step parameters\' 'parameterNames.mat'],'parameterNames');
            app.LoadNameDropDown.Items = app.paramNames;
            app.LoadNameDropDown.Value = app.paramNames{end};
            app.SaveNameEditField.Visible = 'off';
            app.SaveNameEditFieldLabel.Visible = 'off';
            app.SaveButton.Visible = 'off';
            app.SaveParametersButton.Enable = 'on';
            app.ParameterssavedLabel.Visible = 'on';
        end

        % Value changing function: SaveNameEditField
        function SaveNameEditFieldValueChanging(app, event)
            app.paramSaveName = event.Value;
            app.SaveButton.Enable = 'on';
        end

        % Button pushed function: LoadParametersButton
        function LoadParametersButtonPushed(app, event)
            app.LoadNameDropDown.Visible = 'on';
            app.LoadNameDropDownLabel.Visible = 'on';
            app.LoadButton.Visible = 'on';
            app.LoadButton.Enable = 'off';
            app.LoadParametersButton.Enable = 'off';
            app.LoadButton.Enable = 'on';
            app.ParameterssavedLabel.Visible = 'off';
        end

        % Callback function
        function LoadNameEditFieldValueChanged(app, event)
            app.paramLoadName = app.LoadNameEditField.Value;
        end

        % Callback function
        function LoadNameEditFieldValueChanging(app, event)
            app.paramLoadName = event.Value;
            app.LoadButton.Enable = 'on';
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            load([app.savePathBase '\variables\step parameters\' app.paramLoadName '.mat']);
            app.swT = swingDuration;
            app.stDuty = stanceDuty;
            app.bodyT = bodyTranslation;
            app.bodyR = bodyRotation;
            app.sprK = springK;
            app.stepH = stepHeight';
            app.floorL = floorLevel';
            app.maxThetaDot = maxJointVelocity;
            app.SaveLocationTextArea.Value = app.savePathBase;
            app.SwingDurationsEditField.Value = swingDuration;
            app.StanceDutyEditField.Value = stanceDuty;
            app.MaxBodyTranslationmEditField.Value = bodyTranslation;
            app.MaxBodyRotationradEditField.Value = bodyRotation;
            app.SpringKNmradEditField.Value = springK;
            app.MaxJointVelocityradsEditField.Value = maxJointVelocity;
            tableData = [stepHeight'; floorLevel'];
            app.UITable.Data = tableData;
            app.LoadParametersButton.Enable = 'on';
            app.LoadNameDropDown.Visible = 'off';
            app.LoadNameDropDownLabel.Visible = 'off';
            app.LoadButton.Visible = 'off';
        end

        % Value changed function: PrimaryactuatorDropDown
        function PrimaryactuatorDropDownValueChanged(app, event)
            app.actuatorName = app.PrimaryactuatorDropDown.Value;
            if strcmp(app.actuatorName, 'New Actuator')
                app.NewActApp = DefineNewActuator(app);
            end

        end

        % Callback function
        function WalkingDirectionButtonGroupSelectionChanged(app, event)

        end

        % Value changed function: TerrainTypeDropDown
        function TerrainTypeDropDownValueChanged(app, event)
            app.substrateShape = app.TerrainTypeDropDown.Value;
        end

        % Value changed function: LoadNameDropDown
        function LoadNameDropDownValueChanged(app, event)
            app.paramLoadName = app.LoadNameDropDown.Value;
            
        end

        % Value changed function: ContralNormPhaseAngleEditField
        function ContralNormPhaseAngleEditFieldValueChanged(app, event)
            app.contralateralPhase = app.ContralNormPhaseAngleEditField.Value;
        end

        % Value changed function: IpsalNormPhaseAngleEditField
        function IpsalNormPhaseAngleEditFieldValueChanged(app, event)
            app.ipsalateralPhase = app.IpsalNormPhaseAngleEditField.Value;
        end

        % Selection changed function: CalculateneuralparamsButtonGroup
        function CalculateneuralparamsButtonGroupSelectionChanged(app, event)
            app.runNeuralParams = app.CalculateneuralparamsButtonGroup.SelectedObject;
            
        end

        % Value changed function: BodyStructureFileLocationEditField
        function BodyStructureFileLocationEditFieldValueChanged(app, event)

        end

        % Value changing function: BodyStructureFileLocationEditField
        function BodyStructureFileLocationEditFieldValueChanging(app, event)
            app.SaveNewBodyStructureLocationButton.Enable = 'on';
            app.ConfigurationSavedLabel.Visible = 'off';
        end

        % Button pushed function: SaveNewBodyStructureLocationButton
        function SaveNewBodyStructureLocationButtonPushed(app, event)
            app.projectFile = app.BodyStructureFileLocationEditField.Value;
            app.BodyStructureNameDropDown.Enable = "off";
            load([app.savePathBase '\variables\configs\' app.configName ' Config.mat']);
            proj_file = app.projectFile;
            save([app.savePathBase '\variables\configs\' app.configName ' Config.mat'],'bodies','configName','ctr','jointLimits','jointVecs','joints','numJoints','numLegs','organism_name','proj_file');
            app.BodyStructureNameDropDown.Enable = "on";
            app.SaveNewBodyStructureLocationButton.Enable = 'off';
            app.ConfigurationSavedLabel.Visible = 'on';
            app.BodyStructureNameDropDown.Enable = "on";
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.SaveLocationLabel = uilabel(app.UIFigure);
            app.SaveLocationLabel.Text = 'Save Location:';
            app.SaveLocationTextArea = uitextarea(app.UIFigure);
            app.ConfigurationLabel = uilabel(app.UIFigure);
            app.ConfigurationLabel.Text = 'Body Structure Name:';
            app.BodyStructureNameDropDown = uidropdown(app.UIFigure);
            app.BodyStructureNameDropDown.ValueChangedFcn = createCallbackFcn(app, @BodyStructureNameDropDownValueChanged, true);
            app.RunasstaticButtonGroup = uibuttongroup(app.UIFigure);
            app.RunasstaticButtonGroup.Title = 'Run as static?';
            app.NoButton = uiradiobutton(app.RunasstaticButtonGroup);
            app.NoButton.Text = 'No';
            app.NoButton.Value = true;
            app.YesButton = uiradiobutton(app.RunasstaticButtonGroup);
            app.YesButton.Text = 'Yes';
            app.ForarobotButtonGroup = uibuttongroup(app.UIFigure);
            app.ForarobotButtonGroup.Title = 'For a robot?';
            app.NoButton_2 = uiradiobutton(app.ForarobotButtonGroup);
            app.NoButton_2.Text = 'No';
            app.NoButton_2.Value = true;
            app.YesButton_2 = uiradiobutton(app.ForarobotButtonGroup);
            app.YesButton_2.Text = 'Yes';
            app.SteppingParametersLabel = uilabel(app.UIFigure);
            app.SteppingParametersLabel.Text = 'Stepping Parameters:';
            app.StartSolverButton = uibutton(app.UIFigure, 'push');
            app.StartSolverButton.ButtonPushedFcn = createCallbackFcn(app, @StartSolverButtonPushed, true);
            app.StartSolverButton.Text = 'Start Solver';
            app.SwingDurationsEditFieldLabel = uilabel(app.UIFigure);
            app.SwingDurationsEditFieldLabel.Text = 'Swing Duration (s):';
            app.SwingDurationsEditField = uieditfield(app.UIFigure, 'numeric');
            app.SwingDurationsEditField.ValueChangedFcn = createCallbackFcn(app, @SwingDurationsEditFieldValueChanged, true);
            app.StanceDutyLabel = uilabel(app.UIFigure);
            app.StanceDutyLabel.Text = 'Stance Duty:';
            app.StanceDutyEditField = uieditfield(app.UIFigure, 'numeric');
            app.StanceDutyEditField.ValueChangedFcn = createCallbackFcn(app, @StanceDutyEditFieldValueChanged, true);
            app.MaxBodyTranslationmEditFieldLabel = uilabel(app.UIFigure);
            app.MaxBodyTranslationmEditFieldLabel.Text = 'Max Body Translation (m):';
            app.MaxBodyTranslationmEditField = uieditfield(app.UIFigure, 'numeric');
            app.MaxBodyTranslationmEditField.ValueChangedFcn = createCallbackFcn(app, @MaxBodyTranslationmEditFieldValueChanged, true);
            app.BodyRotationradLabel = uilabel(app.UIFigure);
            app.BodyRotationradLabel.Text = 'Max Body Rotation (rad):';
            app.MaxBodyRotationradEditField = uieditfield(app.UIFigure, 'numeric');
            app.MaxBodyRotationradEditField.ValueChangedFcn = createCallbackFcn(app, @MaxBodyRotationradEditFieldValueChanged, true);
            app.SpringKNmradLabel = uilabel(app.UIFigure);
            app.SpringKNmradLabel.Text = 'Spring K (Nm/rad):';
            app.SpringKNmradEditField = uieditfield(app.UIFigure, 'numeric');
            app.SpringKNmradEditField.ValueChangedFcn = createCallbackFcn(app, @SpringKNmradEditFieldValueChanged, true);
            app.MaxJointVelocityradsLabel = uilabel(app.UIFigure);
            app.MaxJointVelocityradsLabel.Text = 'Max Joint Velocity (rad/s):';
            app.MaxJointVelocityradsEditField = uieditfield(app.UIFigure, 'numeric');
            app.MaxJointVelocityradsEditField.ValueChangedFcn = createCallbackFcn(app, @MaxJointVelocityradsEditFieldValueChanged, true);
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'RH'; 'LH'; 'RM'; 'LM'; 'RF'; 'LF'};
            app.UITable.RowName = {'Step Height (m)'; 'Floor Level (m)'};
            app.UITable.ColumnEditable = true;
            app.UITable.CellEditCallback = createCallbackFcn(app, @UITableCellEdit, true);
            app.SaveParametersButton = uibutton(app.UIFigure, 'push');
            app.SaveParametersButton.ButtonPushedFcn = createCallbackFcn(app, @SaveParametersButtonPushed, true);
            app.SaveParametersButton.Text = 'Save Parameters';
            app.UsecustomIfileButtonGroup = uibuttongroup(app.UIFigure);
            app.UsecustomIfileButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @UsecustomIfileButtonGroupSelectionChanged, true);
            app.UsecustomIfileButtonGroup.Title = 'Use custom I file?';
            app.NoButton_3 = uiradiobutton(app.UsecustomIfileButtonGroup);
            app.NoButton_3.Text = 'No';
            app.NoButton_3.Value = true;
            app.YesButton_3 = uiradiobutton(app.UsecustomIfileButtonGroup);
            app.YesButton_3.Text = 'Yes';
            app.EnterIfilenameLabel = uilabel(app.UIFigure);
            app.EnterIfilenameLabel.Text = 'Enter I file name:';
            app.EnterIfilenameEditField = uieditfield(app.UIFigure, 'text');
            app.EnterIfilenameEditField.ValueChangedFcn = createCallbackFcn(app, @EnterIfilenameEditFieldValueChanged, true);
            app.SaveNameEditFieldLabel = uilabel(app.UIFigure);
            app.SaveNameEditFieldLabel.Text = 'Save Name:';
            app.SaveNameEditField = uieditfield(app.UIFigure, 'text');
            app.SaveNameEditField.ValueChangedFcn = createCallbackFcn(app, @SaveNameEditFieldValueChanged, true);
            app.SaveNameEditField.ValueChangingFcn = createCallbackFcn(app, @SaveNameEditFieldValueChanging, true);
            app.SaveButton = uibutton(app.UIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.LoadParametersButton = uibutton(app.UIFigure, 'push');
            app.LoadParametersButton.ButtonPushedFcn = createCallbackFcn(app, @LoadParametersButtonPushed, true);
            app.LoadParametersButton.Text = 'Load Parameters';
            app.LoadButton = uibutton(app.UIFigure, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            app.ParameterssavedLabel = uilabel(app.UIFigure);
            app.ParameterssavedLabel.Text = 'Parameters saved';
            app.PrimaryactuatorDropDownLabel = uilabel(app.UIFigure);
            app.PrimaryactuatorDropDownLabel.Text = 'Primary actuator:';
            app.PrimaryactuatorDropDown = uidropdown(app.UIFigure);
            app.PrimaryactuatorDropDown.Items = {'MX-28', 'New Actuator'};
            app.PrimaryactuatorDropDown.ValueChangedFcn = createCallbackFcn(app, @PrimaryactuatorDropDownValueChanged, true);
            app.PrimaryactuatorDropDown.Value = 'MX-28';
            app.FormultipleactuatorsdefinedirectlyinacustomIfileLabel = uilabel(app.UIFigure);
            app.FormultipleactuatorsdefinedirectlyinacustomIfileLabel.Text = 'For multiple actuators, define directly in a custom I file';
            app.TerrainTypeLabel = uilabel(app.UIFigure);
            app.TerrainTypeLabel.Text = 'Terrain Type:';
            app.TerrainTypeDropDown = uidropdown(app.UIFigure);
            app.TerrainTypeDropDown.Items = {'Flat', 'Ball'};
            app.TerrainTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @TerrainTypeDropDownValueChanged, true);
            app.TerrainTypeDropDown.Value = 'Flat';
            app.LoadNameDropDownLabel = uilabel(app.UIFigure);
            app.LoadNameDropDownLabel.Text = 'Load Name:';
            app.LoadNameDropDown = uidropdown(app.UIFigure);
            app.LoadNameDropDown.ValueChangedFcn = createCallbackFcn(app, @LoadNameDropDownValueChanged, true);
            app.ContralNormPhaseAngleEditFieldLabel = uilabel(app.UIFigure);
            app.ContralNormPhaseAngleEditFieldLabel.Text = 'Contral. Norm. Phase Angle:';
            app.ContralNormPhaseAngleEditField = uieditfield(app.UIFigure, 'numeric');
            app.ContralNormPhaseAngleEditField.ValueChangedFcn = createCallbackFcn(app, @ContralNormPhaseAngleEditFieldValueChanged, true);
            app.IpsalNormPhaseAngleEditFieldLabel = uilabel(app.UIFigure);
            app.IpsalNormPhaseAngleEditFieldLabel.Text = 'Ipsal. Norm. Phase Angle:';
            app.IpsalNormPhaseAngleEditField = uieditfield(app.UIFigure, 'numeric');
            app.IpsalNormPhaseAngleEditField.ValueChangedFcn = createCallbackFcn(app, @IpsalNormPhaseAngleEditFieldValueChanged, true);
            app.CalculateneuralparamsButtonGroup = uibuttongroup(app.UIFigure);
            app.CalculateneuralparamsButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @CalculateneuralparamsButtonGroupSelectionChanged, true);
            app.CalculateneuralparamsButtonGroup.Title = 'Calculate neural params?';
            app.NoButton_4 = uiradiobutton(app.CalculateneuralparamsButtonGroup);
            app.NoButton_4.Text = 'No';
            app.NoButton_4.Value = true;
            app.YesButton_4 = uiradiobutton(app.CalculateneuralparamsButtonGroup);
            app.YesButton_4.Text = 'Yes';
            app.ConfigurationFileLocationLabel = uilabel(app.UIFigure);
            app.ConfigurationFileLocationLabel.Text = 'Body Structure File Location:';
            app.BodyStructureFileLocationEditField = uieditfield(app.UIFigure, 'text');
            app.BodyStructureFileLocationEditField.ValueChangedFcn = createCallbackFcn(app, @BodyStructureFileLocationEditFieldValueChanged, true);
            app.BodyStructureFileLocationEditField.ValueChangingFcn = createCallbackFcn(app, @BodyStructureFileLocationEditFieldValueChanging, true);
            app.SaveNewBodyStructureLocationButton = uibutton(app.UIFigure, 'push');
            app.SaveNewBodyStructureLocationButton.ButtonPushedFcn = createCallbackFcn(app, @SaveNewBodyStructureLocationButtonPushed, true);
            app.SaveNewBodyStructureLocationButton.Text = 'Save New Body Structure Location';
            app.ConfigurationSavedLabel = uilabel(app.UIFigure);
            app.ConfigurationSavedLabel.Text = 'Configuration Saved';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = RunSolver

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end